classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        TimeScope_5  matlab.ui.scope.TimeScope
        TimeScope_4  matlab.ui.scope.TimeScope
        TimeScope_3  matlab.ui.scope.TimeScope
        TimeScope_2  matlab.ui.scope.TimeScope
        TimeScope    matlab.ui.scope.TimeScope
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
        MyHardware % To store the hardware object
        RunLoop    % A logical flag to control the loop
        IsRunning % Logical flag: true = loop runs, false = loop stops
        Device % Description
    end
    
    methods (Access = public)
            function updateState(app, stateID)
            switch stateID
                case 1
                    app.StateLabel.Text = 'Swing-Up';
                    app.StateLamp.Color = [0 0 1]; % blue
                case 2
                    app.StateLabel.Text = 'LQR';
                    app.StateLamp.Color = [0 1 0]; % green
                case 3
                    app.StateLabel.Text = 'Recovery';
                    app.StateLamp.Color = [1 0 0]; % red
                otherwise
                    app.StateLabel.Text = 'Unknown';
                    app.StateLamp.Color = [0.5 0.5 0.5];
            end
        end
        function results = func(app)
            
        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Callback function
        function ButtonPushed(app, event)
            if isempty(app.IsRunning) || app.IsRunning == false
                try
                    % 1. Initialize Hardware (Uncomment and edit for your device)
                    if isempty(app.Device)
                        % app.Device = arduino(); 
                    end
                    
                    app.IsRunning = true;
                    app.Button.Enable = 'off';  % Disable Start
                    app.Button2.Enable = 'on';  % Enable Stop
                    
                    % 2. Loop for data acquisition
                    while app.IsRunning
                        % Read your hardware data here
                        % Example: val = readVoltage(app.Device, 'A0');
                        val = randn(); 
                        
                        % Update TimeScope (requires a time stamp or sample)
                        % For uitimescope, use the step or write method
                        % app.TimeScope.step(val); 
                        
                        % 3. CRITICAL: Allow UI to process the Stop button click
                        drawnow limitrate
                    end
                catch ME
                    uialert(app.UIFigure, ME.message, 'Hardware Error');
                    app.IsRunning = false;
                    app.Button.Enable = 'on';
                end
            end
        end

        % Callback function
        function Button2Pushed(app, event)
            app.IsRunning = false;
            
            app.Button.Enable = 'on';
            app.Button2.Enable = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TimeScope = uitimescope(app.UIFigure);
            app.TimeScope.LegendNames = {'PendAngle'};
            app.TimeScope.LegendVisible = 'on';
            app.TimeScope.Title = 'PendAngle';
            app.TimeScope.YLabel = 'Angle';
            app.TimeScope.YLimits = [-200 200];
            app.TimeScope.YLimitsMethod = 'manual';
            app.TimeScope_2 = uitimescope(app.UIFigure);
            app.TimeScope_2.LegendNames = {'PendVel'};
            app.TimeScope_2.LegendVisible = 'on';
            app.TimeScope_2.Title = 'PendVel';
            app.TimeScope_2.YLabel = 'Velocity';
            app.TimeScope_2.YLimits = [-30 30];
            app.TimeScope_2.YLimitsMethod = 'manual';
            app.TimeScope_3 = uitimescope(app.UIFigure);
            app.TimeScope_3.LegendNames = {'MotorAngle'};
            app.TimeScope_3.LegendVisible = 'on';
            app.TimeScope_3.Title = 'MotorAngle';
            app.TimeScope_3.YLabel = 'Angle';
            app.TimeScope_3.YLimits = [-200 200];
            app.TimeScope_3.YLimitsMethod = 'manual';
            app.TimeScope_4 = uitimescope(app.UIFigure);
            app.TimeScope_4.LegendNames = {'MotoVel'};
            app.TimeScope_4.LegendVisible = 'on';
            app.TimeScope_4.Title = 'MotorVel';
            app.TimeScope_4.YLabel = 'Velocity';
            app.TimeScope_4.YLimits = [-15 15];
            app.TimeScope_4.YLimitsMethod = 'manual';
            app.TimeScope_5 = uitimescope(app.UIFigure);
            app.TimeScope_5.LegendNames = {'Chart:1'};
            app.TimeScope_5.LegendVisible = 'on';
            app.TimeScope_5.Title = 'ControlAction';
            app.TimeScope_5.YLabel = 'Velocity';
            app.TimeScope_5.YLimits = [-2 2];
            app.TimeScope_5.YLimitsMethod = 'manual';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % Bind initialization
    methods (Access = protected)

        function createBindings(app)

            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/Radians to Degrees4:1'], app.TimeScope);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/Radians to Degrees3:1'], app.TimeScope_3);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/From11:1'], app.TimeScope_4);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/From9:1'], app.TimeScope_2);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/Chart:1'], app.TimeScope_5);
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Associate the Simulink Model
            app.Simulation = simulation('Test_Hardware');

            % Create UIFigure and components
            createComponents(app)

            % Create bindings
            createBindings(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end